classdef TeardownApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image4                    matlab.ui.control.Image
        TabGroup                  matlab.ui.container.TabGroup
        ServerTab                 matlab.ui.container.Tab
        PasswordEditField         matlab.ui.control.EditField
        PasswordEditFieldLabel    matlab.ui.control.Label
        UsernameEditField         matlab.ui.control.EditField
        UsernameEditFieldLabel    matlab.ui.control.Label
        GloveControlTab           matlab.ui.container.Tab
        MoveButton                matlab.ui.control.Button
        Part3Lamp                 matlab.ui.control.Lamp
        Part3LampLabel            matlab.ui.control.Label
        Part2Lamp                 matlab.ui.control.Lamp
        Part2LampLabel            matlab.ui.control.Label
        Part1Lamp                 matlab.ui.control.Lamp
        Part1LampLabel            matlab.ui.control.Label
        HomeButton                matlab.ui.control.Button
        VLabel_2                  matlab.ui.control.Label
        VLabel                    matlab.ui.control.Label
        GloveControlLabel         matlab.ui.control.Label
        Volts1EditField           matlab.ui.control.NumericEditField
        Volts1EditFieldLabel      matlab.ui.control.Label
        Volts0EditField           matlab.ui.control.NumericEditField
        Volts0EditFieldLabel      matlab.ui.control.Label
        GloveAxisLabel            matlab.ui.control.Label
        AxisZEditField            matlab.ui.control.NumericEditField
        AxisZEditFieldLabel       matlab.ui.control.Label
        AxisYEditField            matlab.ui.control.NumericEditField
        AxisYEditFieldLabel       matlab.ui.control.Label
        AxisXEditField            matlab.ui.control.NumericEditField
        AxisXEditFieldLabel       matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Image3                    matlab.ui.control.Image
        Image2                    matlab.ui.control.Image
        MQTTConnectionPanel       matlab.ui.container.Panel
        DisconnectedLabel_2       matlab.ui.control.Label
        ROSLamp                   matlab.ui.control.Lamp
        ROSLampLabel              matlab.ui.control.Label
        PortEditField             matlab.ui.control.EditField
        PortEditFieldLabel        matlab.ui.control.Label
        SubscribeButton           matlab.ui.control.StateButton
        TopicEditField            matlab.ui.control.EditField
        TopicEditFieldLabel       matlab.ui.control.Label
        UnsubscribeLabel          matlab.ui.control.Label
        TopicLamp                 matlab.ui.control.Lamp
        TopicLampLabel            matlab.ui.control.Label
        NoneLabel                 matlab.ui.control.Label
        StatusLabel               matlab.ui.control.Label
        ConnectButton             matlab.ui.control.StateButton
        DisconnectedLabel         matlab.ui.control.Label
        MQTTLamp                  matlab.ui.control.Lamp
        MQTTLampLabel             matlab.ui.control.Label
        MQTTBrokerEditField       matlab.ui.control.EditField
        MQTTBrokerEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqttClient      % MQTT client
        mqttTopic       % MQTT topic
        mqttCmdSub      % MQTT command
 
        mqttUser        % MQTT Username
        mqttPass        % MQTT Password 
        robot           % Robot Variable
        joint_states    % Joint State
        

        senderNode      % Sent Node
        ros2Pub         % ROS Publisher
        rosmsg          % ROS Massage
        
        receiverNode
        ros2Sub

    end
    
    methods (Access = private)
        
        function handleMQTTmsg(app,topic,data)
            %disp(topic);
            cmd = jsondecode(data);            
            if isfield(cmd, 'x')
                %app.AxisXEditField.Value = cmd.x;
                %app.AxisYEditField.Value = cmd.y;
                %app.AxisZEditField.Value = cmd.z;
                %app.rosmsg.data = sprintf('{"objective":"CONTROL","x": %0.2f , "y":%0.2f , "z":%0.2f , "volt1":0 , "volt2":0}',cmd.x,cmd.y,cmd.z);
                %send(app.ros2Pub,app.rosmsg);
                app.Volts0EditField.Value = cmd.volts0;
                app.Volts1EditField.Value = cmd.volts1;
            else
                disp("don't have Axis")
            end
          
        end
        
        function rosMsgHandler(app,rosmsg)
            data = jsondecode(rosmsg.data);
            if data.catching == 0

                if data.part1 == 1
                    app.Part1Lamp.Color = 'g';
                else
                    app.Part1Lamp.Color = 'r';
                end
 
                if data.part2 == 1
                    app.Part2Lamp.Color = 'g';
                else
                    app.Part2Lamp.Color = 'r';
                end
 
                if data.part3 == 1
                    app.Part3Lamp.Color = 'g';
                else
                    app.Part3Lamp.Color = 'r';
                end

                if(data.Home == 0)
                    write(app.mqttClient,"teardown/fingers/grasp/","{'servo0':1}");
                end
                
            else
                app.Part1Lamp.Color = 'y';
                app.Part2Lamp.Color = 'y';
                app.Part3Lamp.Color = 'y';


                if(data.Home == 0)
                    write(app.mqttClient,"teardown/fingers/grasp/","{'servo0':0}");
                end
            end
             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize MQTT
            app.MQTTLamp.Color = 'r';
            app.MQTTBrokerEditField.Value = "tcp://172.20.10.19";
            app.PortEditField.Value = "1883";
            app.UsernameEditField.Value = "Natthanan";
            app.mqttUser = app.UsernameEditField.Value;
            app.PasswordEditField.Value = "a0983711099";
            app.mqttPass = app.PasswordEditField.Value;
            
            app.TopicEditField.Value = "teardown/arm/move";
            app.mqttTopic = app.TopicEditField.Value;


            %connect ros
            app.senderNode = ros2node('UI_senderNode');
            app.ros2Pub = ros2publisher(app.senderNode, '/data', "std_msgs/String");
            app.rosmsg = ros2message(app.ros2Pub);

            app.receiverNode = ros2node('receiverNode');
            app.ros2Sub = ros2subscriber(app.receiverNode, '/gripper', "std_msgs/String", @app.rosMsgHandler);
            
            
            app.ROSLamp.Color = 'g';
            app.DisconnectedLabel_2.Text = "Connected";
            app.NoneLabel.Text = "ROS is Connected.";


        end

        % Callback function
        function StartButtonValueChanged(app, event)

        end

        % Button pushed function: HomeButton
        function HomeButtonValueChanged(app, event)
            app.AxisXEditField.Value = 0.4;
            app.AxisYEditField.Value = 0;
            app.AxisZEditField.Value = 0.5;
            app.rosmsg.data = sprintf('{"objective":"HOME"}');
            send(app.ros2Pub,app.rosmsg);
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
             app.mqttClient = mqttclient(app.MQTTBrokerEditField.Value, Port = str2double(app.PortEditField.Value), Username = app.mqttUser, Password = app.mqttPass);
            if app.mqttClient.Connected
                app.MQTTLamp.Color = 'g';
                app.DisconnectedLabel.Text = "Connected";
                app.NoneLabel.Text = "MQTT is Connected. (" + app.MQTTBrokerEditField.Value + ")";
            end
        end

        % Value changed function: SubscribeButton
        function SubscribeButtonValueChanged(app, event)
            app.mqttClient = mqttclient(app.MQTTBrokerEditField.Value, Port = str2double(app.PortEditField.Value), Username = app.mqttUser, Password = app.mqttPass);
            app.mqttTopic = app.TopicEditField.Value;
            
            app.mqttCmdSub = subscribe(app.mqttClient, app.mqttTopic, Callback=@app.handleMQTTmsg);

            app.TopicLamp.Color = 'g';
            app.UnsubscribeLabel.Text = "Subscribe";
            app.NoneLabel.Text = "Topic In and Topic Out is Subscribe.";
        end

        % Callback function
        function XButton_PosValueChanged(app, event)
 
        end

        % Callback function
        function XButton_NegValueChanged(app, event)
   
        end

        % Callback function
        function YButton_PosValueChanged(app, event)
 
        end

        % Callback function
        function YButton_NegValueChanged(app, event)

        end

        % Callback function
        function ZButton_PosValueChanged(app, event)

        end

        % Callback function
        function ZButton_NegValueChanged(app, event)
 
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            x = app.AxisXEditField.Value;
            y = app.AxisYEditField.Value;
            z = app.AxisZEditField.Value;
            v1 = app.Volts0EditField.Value;
            v2 = app.Volts1EditField.Value;
            app.rosmsg.data = sprintf('{"objective":"CONTROL","x": %0.2f , "y":%0.2f , "z":%0.2f , "volt1":%0.2f , "volt2":%0.2f}',x,y,z,v1,v2);
            send(app.ros2Pub,app.rosmsg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MQTTConnectionPanel = uipanel(app.UIFigure);
            app.MQTTConnectionPanel.Title = 'MQTT Connection';
            app.MQTTBrokerEditFieldLabel = uilabel(app.MQTTConnectionPanel);
            app.MQTTBrokerEditFieldLabel.Text = 'MQTT Broker';
            app.MQTTBrokerEditField = uieditfield(app.MQTTConnectionPanel, 'text');
            app.MQTTLampLabel = uilabel(app.MQTTConnectionPanel);
            app.MQTTLampLabel.Text = 'MQTT';
            app.MQTTLamp = uilamp(app.MQTTConnectionPanel);
            app.DisconnectedLabel = uilabel(app.MQTTConnectionPanel);
            app.DisconnectedLabel.Text = 'Disconnected';
            app.ConnectButton = uibutton(app.MQTTConnectionPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.StatusLabel = uilabel(app.MQTTConnectionPanel);
            app.StatusLabel.Text = 'Status :';
            app.NoneLabel = uilabel(app.MQTTConnectionPanel);
            app.NoneLabel.Text = 'None';
            app.TopicLampLabel = uilabel(app.MQTTConnectionPanel);
            app.TopicLampLabel.Text = 'Topic';
            app.TopicLamp = uilamp(app.MQTTConnectionPanel);
            app.UnsubscribeLabel = uilabel(app.MQTTConnectionPanel);
            app.UnsubscribeLabel.Text = 'Unsubscribe';
            app.TopicEditFieldLabel = uilabel(app.MQTTConnectionPanel);
            app.TopicEditFieldLabel.Text = 'Topic';
            app.TopicEditField = uieditfield(app.MQTTConnectionPanel, 'text');
            app.SubscribeButton = uibutton(app.MQTTConnectionPanel, 'state');
            app.SubscribeButton.ValueChangedFcn = createCallbackFcn(app, @SubscribeButtonValueChanged, true);
            app.SubscribeButton.Text = 'Subscribe';
            app.PortEditFieldLabel = uilabel(app.MQTTConnectionPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.MQTTConnectionPanel, 'text');
            app.ROSLampLabel = uilabel(app.MQTTConnectionPanel);
            app.ROSLampLabel.Text = 'ROS';
            app.ROSLamp = uilamp(app.MQTTConnectionPanel);
            app.DisconnectedLabel_2 = uilabel(app.MQTTConnectionPanel);
            app.DisconnectedLabel_2.Text = 'Disconnected';
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'banner_bottom.jpg';
            app.Label = uilabel(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ServerTab = uitab(app.TabGroup);
            app.ServerTab.Title = 'Server';
            app.UsernameEditFieldLabel = uilabel(app.ServerTab);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.ServerTab, 'text');
            app.PasswordEditFieldLabel = uilabel(app.ServerTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.ServerTab, 'text');
            app.GloveControlTab = uitab(app.TabGroup);
            app.GloveControlTab.Title = 'Glove Control';
            app.AxisXEditFieldLabel = uilabel(app.GloveControlTab);
            app.AxisXEditFieldLabel.Text = 'Axis X';
            app.AxisXEditField = uieditfield(app.GloveControlTab, 'numeric');
            app.AxisYEditFieldLabel = uilabel(app.GloveControlTab);
            app.AxisYEditFieldLabel.Text = 'Axis Y';
            app.AxisYEditField = uieditfield(app.GloveControlTab, 'numeric');
            app.AxisZEditFieldLabel = uilabel(app.GloveControlTab);
            app.AxisZEditFieldLabel.Text = 'Axis Z';
            app.AxisZEditField = uieditfield(app.GloveControlTab, 'numeric');
            app.GloveAxisLabel = uilabel(app.GloveControlTab);
            app.GloveAxisLabel.Text = 'Glove Axis ';
            app.Volts0EditFieldLabel = uilabel(app.GloveControlTab);
            app.Volts0EditFieldLabel.Text = 'Volts0';
            app.Volts0EditField = uieditfield(app.GloveControlTab, 'numeric');
            app.Volts1EditFieldLabel = uilabel(app.GloveControlTab);
            app.Volts1EditFieldLabel.Text = 'Volts1';
            app.Volts1EditField = uieditfield(app.GloveControlTab, 'numeric');
            app.GloveControlLabel = uilabel(app.GloveControlTab);
            app.GloveControlLabel.Text = 'Glove Control';
            app.VLabel = uilabel(app.GloveControlTab);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.GloveControlTab);
            app.VLabel_2.Text = 'V';
            app.HomeButton = uibutton(app.GloveControlTab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonValueChanged, true);
            app.HomeButton.Text = 'Home';
            app.Part1LampLabel = uilabel(app.GloveControlTab);
            app.Part1LampLabel.Text = 'Part1';
            app.Part1Lamp = uilamp(app.GloveControlTab);
            app.Part2LampLabel = uilabel(app.GloveControlTab);
            app.Part2LampLabel.Text = 'Part2';
            app.Part2Lamp = uilamp(app.GloveControlTab);
            app.Part3LampLabel = uilabel(app.GloveControlTab);
            app.Part3LampLabel.Text = 'Part3';
            app.Part3Lamp = uilamp(app.GloveControlTab);
            app.MoveButton = uibutton(app.GloveControlTab, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'banner_top.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TeardownApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end